classdef CompressorFaultApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainAppMenu                     matlab.ui.container.Menu
        HowDetectionWorksMenu           matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        ContactUsLabel                  matlab.ui.control.Label
        TermsLabel                      matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        SystemStatusPanel               matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        RedFaultDetectedLabel           matlab.ui.control.Label
        GreenNoFaultDetectedLabel       matlab.ui.control.Label
        WaitingforaudioinputLabel       matlab.ui.control.Label
        FeatureInformationPanel         matlab.ui.container.Panel
        SpectralEnergyEditField         matlab.ui.control.NumericEditField
        SpectralEnergyEditFieldLabel    matlab.ui.control.Label
        SpectralCentroidEditField       matlab.ui.control.NumericEditField
        SpectralCentroidEditFieldLabel  matlab.ui.control.Label
        RMSEnergyEditField              matlab.ui.control.NumericEditField
        RMSEnergyEditFieldLabel         matlab.ui.control.Label
        TheExtraEnergyappearinginnewplacescatchingearlystagefaultsLabel  matlab.ui.control.Label
        MachienescentreofgravitywhereinthefrequencyrangeisthefaulthappeningLabel  matlab.ui.control.Label
        RootMeanSquareEnergyLowRMSmeansquietsmoothrunningmachieneLabel  matlab.ui.control.Label
        ClassificationResultPanel       matlab.ui.container.Panel
        PredictionEditField             matlab.ui.control.EditField
        PredictionEditFieldLabel        matlab.ui.control.Label
        LevelOfConfidenceEditField      matlab.ui.control.NumericEditField
        LevelOfConfidenceEditFieldLabel  matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        WhichkindoffaultisitLabel       matlab.ui.control.Label
        hatPanel                        matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        MainControlPanel                matlab.ui.container.Panel
        SelectedFileEditField           matlab.ui.control.EditField
        SelectedFileEditFieldLabel      matlab.ui.control.Label
        ClassifyNextAudioLabel          matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        ShowFeaturesButton              matlab.ui.control.Button
        ClassifyButton                  matlab.ui.control.Button
        LoadAudioButton                 matlab.ui.control.Button
        SparkyTheSmartAirCompressorFaultDetectionAppLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        AudioData % Stores the sound wave
        SampleRate % Stores the audio speed
        Features % Stores the extracted data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadAudioButton
        function LoadAudioButtonPushed(app, event)
           %open file picker
            [file, path] = uigetfile('*.wav', 'Select Audio File');

            %stop if user cancels
            if isequal(file,0)
                return;
            end

            %read the audio and save itto the app (like self audio.data)
            [app.AudioData, app.SampleRate] = audioread(fullfile(path, file));

            %updates the text on screen
            app.SelectedFileEditField.Value = file;

            %Ploting the wave in the axes box
            plot(app.UIAxes, app.AudioData);

            %turning on the classify button now data is ready
            app.ClassifyButton.Enable = 'on';


        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            %extract the features 
         
            app.Features = extractAudioFeatures(app.AudioData, app.SampleRate);

            % Updates Feature UI fields immediately so they aren't 0
            app.RMSEnergyEditField.Value = app.Features.RMS;
            app.SpectralCentroidEditField.Value = app.Features.Centroid;
            app.SpectralEnergyEditField.Value = app.Features.RMS^2; %Approximation

            % Gets the prediction and the confidence score
            load('bestModel.mat','bestModel');
            [prediction,scores] = bestModel.predictFcn(app.Features);

            % Converts the raw scores to probablilites
            T = 0.5;
            probScores = exp(scores/T) ./ sum(exp(scores/T), 2);
            confValue = max(probScores, [], 2);

            % Converts the prediction to a string for display
            predictionStr = string(prediction);
            app.PredictionEditField.Value = predictionStr;
            app.LevelOfConfidenceEditField.Value = confValue * 100;

            %lamp
            if strcmpi(predictionStr, 'Healthy')
                app.Lamp.Color = 'green';
                app.WaitingforaudioinputLabel.Text = 'No Fault Detected';
            else
                app.Lamp.Color = 'red';
                app.WaitingforaudioinputLabel.Text = ['Fault detected: ' predictionStr];
            end

            %if model supports scores
            %app.LevelOfConfidenceEditField.Value = 0;

           %unlock reset feature
           app.ShowFeaturesButton.Enable = 'on';
           app.ResetButton.Enable = 'on';

          
        end

        % Button pushed function: ShowFeaturesButton
        function ShowFeaturesButtonPushed(app, event)
            
            %Pull the feature values by col name

            rmsVal = app.Features.RMS;
            centroidVal = app.Features.Centroid;

            %checks if table has spectral energy col if not we find RMS
            %squared as aprox
            spectralVal = rmsVal^2;

            % display values on UI labels
            app.RMSEnergyEditField.Value = rmsVal; %RMS Energy
            app.SpectralCentroidEditField.Value = centroidVal; %Spectral Central
            app.SpectralEnergyEditField.Value = spectralVal; %Spectral Energy

%stat update

            app.WaitingforaudioinputLabel.Text = 'Features dsiplayed';
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %clear data
            app.AudioData = [];
            app.SampleRate = [];
            app.Features = [];

            %clear the plot graph
            cla(app.UIAxes);

            %reset feature feilds to zero

            app.RMSEnergyEditField.Value = 0;
            app.SpectralCentroidEditField.Value = 0;
            app.SpectralEnergyEditField.Value = 0;

            % Clears and resets the Classification result section
            app.PredictionEditField.Value = '';
            app.LevelOfConfidenceEditField.Value = 0;

            %lamp
            app.Lamp.Color = 'green';

            %status message
            app.WaitingforaudioinputLabel.Text = 'Waiting For Audio Input';

            %re-lock the buttons
            app.ClassifyButton.Enable = 'off';
            app.ShowFeaturesButton.Enable = 'off';
            app.ResetButton.Enable = 'off';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainAppMenu = uimenu(app.UIFigure);
            app.MainAppMenu.Text = 'Main App';
            app.HowDetectionWorksMenu = uimenu(app.UIFigure);
            app.HowDetectionWorksMenu.Text = 'How Detection Works';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SparkyTheSmartAirCompressorFaultDetectionAppLabel = uilabel(app.GridLayout);
            app.SparkyTheSmartAirCompressorFaultDetectionAppLabel.Text = ' Sparky The Smart Air Compressor Fault Detection App';
            app.MainControlPanel = uipanel(app.GridLayout);
            app.MainControlPanel.Title = 'Main Control';
            app.LoadAudioButton = uibutton(app.MainControlPanel, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.LoadAudioButton.Text = 'Load Audio';
            app.ClassifyButton = uibutton(app.MainControlPanel, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Enable = 'off';
            app.ClassifyButton.Text = 'Classify';
            app.ShowFeaturesButton = uibutton(app.MainControlPanel, 'push');
            app.ShowFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFeaturesButtonPushed, true);
            app.ShowFeaturesButton.Enable = 'off';
            app.ShowFeaturesButton.Text = 'Show Features';
            app.ResetButton = uibutton(app.MainControlPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset ';
            app.Label_11 = uilabel(app.MainControlPanel);
            app.Label_11.Text = '';
            app.ClassifyNextAudioLabel = uilabel(app.MainControlPanel);
            app.ClassifyNextAudioLabel.Text = 'Classify Next Audio';
            app.SelectedFileEditFieldLabel = uilabel(app.MainControlPanel);
            app.SelectedFileEditFieldLabel.Text = 'Selected File:';
            app.SelectedFileEditField = uieditfield(app.MainControlPanel, 'text');
            app.SelectedFileEditField.Value = 'No File Selected';
            app.hatPanel = uipanel(app.GridLayout);
            app.hatPanel.Title = 'hat';
            app.UIAxes = uiaxes(app.hatPanel);
            title(app.UIAxes, 'Audio Waveform')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.ClassificationResultPanel = uipanel(app.GridLayout);
            app.ClassificationResultPanel.Title = 'Classification Result';
            app.WhichkindoffaultisitLabel = uilabel(app.ClassificationResultPanel);
            app.WhichkindoffaultisitLabel.Text = 'Which kind of fault is it';
            app.Label_10 = uilabel(app.ClassificationResultPanel);
            app.Label_10.Text = '';
            app.LevelOfConfidenceEditFieldLabel = uilabel(app.ClassificationResultPanel);
            app.LevelOfConfidenceEditFieldLabel.Text = 'Level Of Confidence:';
            app.LevelOfConfidenceEditField = uieditfield(app.ClassificationResultPanel, 'numeric');
            app.LevelOfConfidenceEditField.ValueDisplayFormat = '%.2f%%';
            app.PredictionEditFieldLabel = uilabel(app.ClassificationResultPanel);
            app.PredictionEditFieldLabel.Text = 'Prediction:';
            app.PredictionEditField = uieditfield(app.ClassificationResultPanel, 'text');
            app.FeatureInformationPanel = uipanel(app.GridLayout);
            app.FeatureInformationPanel.Title = 'Feature Information ';
            app.RootMeanSquareEnergyLowRMSmeansquietsmoothrunningmachieneLabel = uilabel(app.FeatureInformationPanel);
            app.RootMeanSquareEnergyLowRMSmeansquietsmoothrunningmachieneLabel.Text = 'Root Mean Square Energy - Low RMS means quiet smooth running machiene';
            app.MachienescentreofgravitywhereinthefrequencyrangeisthefaulthappeningLabel = uilabel(app.FeatureInformationPanel);
            app.MachienescentreofgravitywhereinthefrequencyrangeisthefaulthappeningLabel.Text = 'Machienes centre of gravity - where in the frequency range is the fault happening';
            app.TheExtraEnergyappearinginnewplacescatchingearlystagefaultsLabel = uilabel(app.FeatureInformationPanel);
            app.TheExtraEnergyappearinginnewplacescatchingearlystagefaultsLabel.Text = 'The Extra Energy appearing in new places - catching early stage faults';
            app.RMSEnergyEditFieldLabel = uilabel(app.FeatureInformationPanel);
            app.RMSEnergyEditFieldLabel.Text = 'RMS Energy:';
            app.RMSEnergyEditField = uieditfield(app.FeatureInformationPanel, 'numeric');
            app.SpectralCentroidEditFieldLabel = uilabel(app.FeatureInformationPanel);
            app.SpectralCentroidEditFieldLabel.Text = 'Spectral Centroid:';
            app.SpectralCentroidEditField = uieditfield(app.FeatureInformationPanel, 'numeric');
            app.SpectralEnergyEditFieldLabel = uilabel(app.FeatureInformationPanel);
            app.SpectralEnergyEditFieldLabel.Text = 'Spectral Energy:';
            app.SpectralEnergyEditField = uieditfield(app.FeatureInformationPanel, 'numeric');
            app.SystemStatusPanel = uipanel(app.GridLayout);
            app.SystemStatusPanel.Title = 'System Status';
            app.WaitingforaudioinputLabel = uilabel(app.SystemStatusPanel);
            app.WaitingforaudioinputLabel.Text = 'Waiting for audio input';
            app.GreenNoFaultDetectedLabel = uilabel(app.SystemStatusPanel);
            app.GreenNoFaultDetectedLabel.Text = 'Green: No Fault Detected';
            app.RedFaultDetectedLabel = uilabel(app.SystemStatusPanel);
            app.RedFaultDetectedLabel.Text = 'Red: Fault Detected';
            app.Lamp = uilamp(app.SystemStatusPanel);
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.GridLayout);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.GridLayout);
            app.Label_9.Text = '';
            app.TermsLabel = uilabel(app.GridLayout);
            app.TermsLabel.Text = 'Terms ';
            app.ContactUsLabel = uilabel(app.GridLayout);
            app.ContactUsLabel.Text = 'Contact Us';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CompressorFaultApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end